classdef annotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        mnOpenRecording       matlab.ui.container.Menu
        mnSaveAnnotations     matlab.ui.container.Menu
        mnExit                matlab.ui.container.Menu
        glMain                matlab.ui.container.GridLayout
        glToolbar             matlab.ui.container.GridLayout
        btnZoomAll            matlab.ui.control.Button
        btnZoomSel            matlab.ui.control.Button
        btnPitchMark          matlab.ui.control.Button
        btnSave               matlab.ui.control.Button
        glStatusbar           matlab.ui.container.GridLayout
        CursorEditFieldLabel  matlab.ui.control.Label
        txCursor              matlab.ui.control.EditField
        DEBUGEditFieldLabel   matlab.ui.control.Label
        txDebug               matlab.ui.control.EditField
        TabGroup              matlab.ui.container.TabGroup
        SignalTab             matlab.ui.container.Tab
        GridLayout            matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        glWavebar             matlab.ui.container.GridLayout
        axWavebar             matlab.ui.control.UIAxes
        btnWavebarDec         matlab.ui.control.Button
        btnWavebarInc         matlab.ui.control.Button
        SliderLabel           matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        PropertiesTab         matlab.ui.container.Tab
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        btnSortProperties     matlab.ui.control.Button
        tbProperties          matlab.ui.control.Table
    end

    properties (Access = private)
      % General                                    % -------------------
      DEBUG    = true;                             % Debug flag
      dirty    = false;                            % Dirty flag
      name     = 'Annotator App';                  % App name
      zcL      = 50;                               % Zero-cross. detection window
      zcT      = 0.01;                             % Zero-cross. detection threshold
      dirHome  = '../';                            % vVZ home 
      dirWav   = 'waves/';                         % relative to dirHome
      dirLab   = 'labels/';                        % relative to dirHome
      scrInc   = 0.01;                             % Scroll increment
      
      % App state                                  % -------------------
      wave     = [];                               % Current wave data
      rid      = [];                               % Recording ID
      kSelDrag = -1;                               % Initial mouse drag sample index
      plSel    = [];                               % Plot selection
      plPm     = [];                               % Pitch marks
      wbThumb  = [];                               % Wavebar thumb
      
      % Tool tip texts                             % -------------------
      ttApmEna = 'Automatically pitch-mark';
      ttApmDis = ['Automatically pitch-mark. '                       ...
                  'Disabled because selection is empty or a point. ' ...
                  'Select one cycle!'];
    end

    % UI utilities
    methods (Access = private)
    
      function reset(app)
        % Reset UI
        app.clearSelection();
        app.clearPitchMarks();
        cla(app.UIAxes,'reset');
        cla(app.axWavebar,'reset');
        app.UIAxes.Toolbar.Visible    = 'off';
        app.axWavebar.Title.String    = '';
        app.axWavebar.XLabel.String   = '';
        app.axWavebar.YLabel.String   = '';
        app.axWavebar.XTick           = [];
        app.axWavebar.YTick           = [];
        app.axWavebar.Visible         = 'off';
        app.axWavebar.Toolbar.Visible = 'off';
        
        % Reset properties
        app.dirty    = false;
        app.rid      = [];
        app.wave     = [];
        app.plSel    = [];
        app.plPm     = [];
        app.wbThumb  = [];
        app.kSelDrag = -1;
        
        % Update UI
        app.updateUI();
      end
        
      function updateUI(app)
          
        % Set title and save button state
        if isempty(app.wave)
          app.UIFigure.Name = app.name;
          app.mnSaveAnnotations.Enable = false;
          app.btnSave.Enable = false;
        else
          if app.dirty
            app.UIFigure.Name = [app.name ' - ' app.rid ' *'];
            app.mnSaveAnnotations.Enable = true;
            app.btnSave.Enable = true;
          else
            app.UIFigure.Name = [app.name ' - ' app.rid];
            app.mnSaveAnnotations.Enable = false;
            app.btnSave.Enable = false;
          end
        end
        
        % Tab dependend actions
        switch app.TabGroup.SelectedTab
          case app.SignalTab
            app.updateUiSignalTab();
          case app.PropertiesTab
            app.updateUiPropertiesTab();
        end
      end
  
      function updateUiSignalTab(app)
        if isempty(app.wave)
          app.btnZoomSel.Enable = false;
          app.btnZoomAll.Enable = false;
          app.btnPitchMark.Enable = false;
          app.btnPitchMark.Tooltip = app.ttApmDis;
          app.Slider.Enable = false;
          app.btnWavebarDec.Enable = false;
          app.btnWavebarInc.Enable = false;
          app.txCursor.Value = '(n/a)';
          app.updateWavebar();
          return;
        end
        K = length(app.wave);
        s = app.getSelection();
        if ~isempty(s) && s(1)~=s(2)
          app.btnZoomSel.Enable    = true;
          app.btnPitchMark.Enable  = true;
          app.btnPitchMark.Tooltip = app.ttApmEna;
        else
          app.btnZoomSel.Enable    = false;
          app.btnPitchMark.Enable  = false;
          app.btnPitchMark.Tooltip = app.ttApmDis;
        end
        xl = app.UIAxes.XLim;
        if xl(1)>0 || xl(2)<K-1  % Zoom-in state
          app.btnZoomAll.Enable = true;
          app.Slider.Enable = true;
          l = K-1-(xl(2)-xl(1));
          app.Slider.Value = xl(1)/l;
        else % Zoom-all state
          app.btnZoomAll.Enable = false;
          app.Slider.Enable = false;
          app.Slider.Value = 0;
        end
        app.btnWavebarDec.Enable = (xl(1)>0  );
        app.btnWavebarInc.Enable = (xl(2)<K-1);
        app.updateWavebar();
      end
      
      function updateUiPropertiesTab(app)
        app.btnZoomSel.Enable = false;
        app.btnZoomAll.Enable = false;
        app.btnPitchMark.Enable = false;
        app.btnPitchMark.Tooltip = app.ttApmDis;
        app.txCursor.Value = '(n/a)';     
        
        if ~isempty(app.wave)
          app.tbProperties.Enable = 'on';
          app.btnSortProperties.Enable = true;
        else
          app.tbProperties.Enable = 'off';
          app.btnSortProperties.Enable = false;
        end

      end
  
      function updateWavebar(app)
        if ~isempty(app.wbThumb)
          delete(app.wbThumb);
          app.wbThumb = [];
        end
        if ~isempty(app.wave)
          yl = app.axWavebar.YLim;
          xl = app.UIAxes.XLim;
          dim = [xl(1) yl(1) xl(2)-xl(1) yl(2)-yl(1)];
          app.wbThumb = rectangle(app.axWavebar,'Position',dim, ...
            'FaceColor',[0 0 0 0.2],'EdgeColor','none');
          app.axWavebar.YLim = yl;
        else
        end
      end
  
      function addToRecMenu(app,rid)
        r = strsplit(rid,'/');
        m = app.mnOpenRecording;
        for i=1:length(r)
          m = app.addSubMenu(m,r{i});
          if i==length(r)
            m.UserData = rid;          
          end
        end
      end
  
      function sm = addSubMenu(app,m,name)
        sm = 0;
        for i=1:length(m.Children)
          cld = m.Children(i);
          if strcmp(cld.Text,name)
            sm = cld;
          end
        end
        if sm==0
          sm = uimenu(m);
          sm.MenuSelectedFcn = createCallbackFcn(app, ...
            @mnOpenRecordingSelected, true);
          sm.Text = name;
        end
      end

      function b = mouseInPlot(app)
        b  = false;
        cp = app.UIFigure.CurrentPoint;
        ap = app.UIAxes.InnerPosition;
        if cp(1)<ap(1) || cp(1)>ap(1)+ap(3) || ... % out of horizonal limits
           cp(2)<ap(2) || cp(2)>ap(2)+ap(4)        % out of vertical limits
          return;
        end
        b = true;
      end
 
      function beginSelDrag(app)
        if app.mouseInPlot()
          k = round(app.UIAxes.CurrentPoint(1,1));
          app.kSelDrag = k;
          app.setSelection(k,k);
          app.selectionChanging(k,k);
        end
      end
 
      function selDrag(app)
        if app.mouseInPlot() && app.kSelDrag>=0
          k = round(app.UIAxes.CurrentPoint(1,1));
          app.setSelection(k,app.kSelDrag);
          app.selectionChanging(k,app.kSelDrag);
        end
      end
 
      function endSelDrag(app)
        if app.mouseInPlot()
          k = round(app.UIAxes.CurrentPoint(1,1));
          if app.kSelDrag>=0
            app.setSelection(k,app.kSelDrag);
            app.selectionChanged(k,app.kSelDrag);
          end
        end
        app.kSelDrag = -1;
      end
  
      function cleanupPropsTable(app)
        function tmrFnc(obj,~,~)
          data  = app.tbProperties.Data;
          if ~isempty(data)
            i = 1;
            while i<=height(data)
              if strlength(data.tier{i})==0
               data(i,:) = [];
              else
                i = i+1;
              end
            end
            data = [data; {'',''}];
          else
            tier  = {''};
            value = {''};
            data = table(tier,value);
          end
          app.tbProperties.Data = data;
          stop(obj);
          delete(obj);
        end
        
        tmr = timer();
        tmr.TimerFcn = @tmrFnc;
        tmr.StartDelay = 0.1;
        start(tmr);
      end
  
      function ok = checkSave(app)
        ok = true;
        if ~app.dirty; return; end
        msg   = 'Do you want to save changed annotations?';
        title = 'Confirm Save';
        sel = uiconfirm(app.UIFigure,msg,title, ...
              'Options',{'Save','Discard Changes','Cancel'},...
              'DefaultOption',1,'CancelOption',3);
        switch sel
          case 'Save'
            [status,msg] = app.saveAnnotations();
            if status==0
              title = 'Save Failed';
              uialert(app.UIFigure,msg,title,'Icon','error');
              ok = false;
            else
              ok = true;
            end
          case 'Discard Changes'
            ok = true;
          case 'Cancel'
            ok = false;
        end
      end

    end
    
    % UI Operations
    methods (Access = private)
  
      function setSelection(app,k0,k1)
        if ~isempty(app.wave)
          K  = length(app.wave);
          yl = app.UIAxes.YLim;
          if ~isempty(app.plSel)
            delete(app.plSel); 
            app.plSel = [];
          end
          if k0>=0 && k1>=0
            if k0>k1; k=k1; k1=k0; k0=k; end
            if k1>=K; k1=K-1; end
            if k0~=k1
              dim = [k0 yl(1) k1-k0 yl(2)-yl(1)];
              app.plSel = rectangle(app.UIAxes,'Position',dim, ...
                'FaceColor',[0 0.5 1 0.1],'EdgeColor','none');
            else
              dim = [k0 yl(1) 0 yl(2)-yl(1)];              
              app.plSel = rectangle(app.UIAxes,'Position',dim, ...
                'FaceColor','none','EdgeColor',[0 0.5 1]);
            end
            app.UIAxes.YLim = yl;
          end
        end
        app.updateUI();
      end
  
      function s = getSelection(app)
        s = [];
        if ~isempty(app.plSel)
          s(1) = app.plSel.Position(1);
          s(2) = s(1) + app.plSel.Position(3);
        end
      end
  
      function clearSelection(app)
        app.setSelection(-1,-1);
      end
  
      function setZoom(app,k0,k1)
        if isempty(app.wave); return; end
        K = length(app.wave);
        if k0>k1; k=k1; k1=k0; k0=k; end
        if k0< 0; k0 = 0;   end
        if k1>=K; k1 = K-1; end
        if k0==k1; return; end
        app.UIAxes.XLim = [k0 k1];
        app.updateUI();
      end
    
      function setPitchMarks(app,pm)
        app.clearPitchMarks();
        app.plPm = cell(length(pm),1);
        yl = app.UIAxes.YLim;
        for i=1:length(pm)
          k   = pm(i);
          dim = [k -1 0 2];              
          app.plPm{i} = rectangle(app.UIAxes,'Position',dim, ...
            'FaceColor','none','EdgeColor',[1 0.75 0]);
        end
        app.clearSelection();
        app.UIAxes.YLim = yl;
        app.dirty = true;
        app.updateUI();
      end
  
      function pm = getPitchMarks(app)
        pm = zeros(length(app.plPm),1);
        for i=1:length(app.plPm)
          obj   = app.plPm{i};
          pm(i) = obj.Position(1);
        end
      end
  
      function clearPitchMarks(app)
        for i=1:length(app.plPm)
          delete(app.plPm{i});
        end
        app.plPm  = [];
        app.dirty = true;
        app.updateUI();
      end
  
    end
    
    % Secondary callbacks
    methods (Access = private)
  
      function selectionChanging(app,k0,k1) %#ok
        % Nothing to be done...
      end
  
      function selectionChanged(app,k0,k1) %#ok
        % Nothing to be done...
      end
  
    end
    
    % Signal and label utilities
    methods (Access = private)
        
      function loadRecording(app,rid)
        if ~app.checkSave(); return; end
        
        msg = sprintf('Loading recording "%s"',rid);
        d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
             'Message',msg,'Indeterminate','on');

        % Reset app
        app.reset();
        
        % Load recording
        [wav,pm,props,msg] = vVZtools.loadRecording(rid);
        if ~isempty(wav)
            
          % Signal
          app.wave = wav;
          app.rid  = rid;
          K = length(app.wave);
          plot(app.UIAxes,0:K-1,app.wave,'Color','black');
          app.UIAxes.XLim = [0,K-1];
          plot(app.axWavebar,0:K-1,app.wave,'Color','black');
          app.axWavebar.XLim = [0,K-1];

          % Pitch marks
          if ~isempty(pm)
            app.setPitchMarks(pm);
            app.dirty = false;
          end
          
          % Propteries table
          app.tbProperties.Data = props;
          app.cleanupPropsTable();
          
        else
          uialert(app.UIFigure,msg,'Open Recording Failed','Icon','error');
        end
        
        % Finish
        app.updateUI();
        close(d);
      end
  
      function [status,msg] = saveAnnotations(app)
        if ~app.dirty
          status = 1;
          msg    = 'ok (no changes to save)';
          return;
        end
        pm    = app.getPitchMarks();
        props = app.tbProperties.Data;
        [status,msg] = vVZtools.saveAnnotations(app.rid,pm,props);
      end
  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

          % Tweak UI
          set(0,'units','pixels');
          ss = get(0,'screensize');
          w  = round(0.95*ss(3));
          h  = 480;
          x  = round((ss(3)-w)/2);
          y  = round(ss(4)-h-round(0.1*h));
          app.UIFigure.Position = [x y w h];

          d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Loading recordings list','Indeterminate','on');
            
          % DEBUGGIN...
          if app.DEBUG
            close all; clc;
          end
            
          % Normalize folder names
          l = dir([app.dirHome app.dirWav]);
          app.dirWav = l(1).folder;
          l = dir([app.dirHome app.dirLab]);
          app.dirLab = l(1).folder;
          
          % Search wave files and build recordings list
          l = dir([app.dirWav '/**/*.wav']);
          for i=1:length(l)
            rec = strrep(l(i).folder,app.dirWav,'');
            rec = strrep(rec,'\','/');
            rec = [ rec(2:length(rec)) '/' strrep(l(i).name,'.wav','')];
            app.addToRecMenu(rec);
          end
                    
          % Initial update
          app.reset();
          app.updateUI();
          close(d);
        end

        % Callback function: UIFigure, UIFigure, UIFigure
        function UIFigureWindowButton(app, event)
          if app.TabGroup.SelectedTab~=app.SignalTab
            return;
          end
          switch event.EventName
            case 'WindowMousePress'
              app.beginSelDrag();
            case 'WindowMouseRelease'
              app.endSelDrag();
            case 'WindowMouseMotion'
              app.selDrag();
              if app.mouseInPlot() && ~isempty(app.wave)
                k = round(app.UIAxes.CurrentPoint(1,1));
                K = length(app.wave);
                if k< 0; k=0;   end
                if k>=K; k=K-1; end
                s = sprintf('k=%d, x(k)=%4.3f',k,app.wave(k+1));
                app.txCursor.Value = s;
              else
                app.txCursor.Value = '(n/a)';
              end
          end
        end

        % Menu selected function: mnOpenRecording
        function mnOpenRecordingSelected(app, event)
          if ~isempty(event.Source.UserData)
            app.loadRecording(event.Source.UserData);
          end
        end

        % Menu selected function: mnExit
        function mnExitSelected(app, event)
          close(app.UIFigure);
        end

        % Callback function: Slider, Slider
        function ScrollSliderValue(app, event)
          if ~isempty(app.wave)
            v  = event.Value;
            K  = length(app.wave);
            xl = app.UIAxes.XLim;
            l  = K-1-(xl(2)-xl(1));
            app.UIAxes.XLim = [round(v*l) round(v*l)+xl(2)-xl(1)];
          else
          end
          app.updateUI();
        end

        % Button pushed function: btnZoomSel
        function btnZoomSelButtonPushed(app, event)
          if isempty(app.wave); return; end
          s = app.getSelection();
          if ~isempty(s)
            app.setZoom(s(1),s(2));
            app.clearSelection();
          end
        end

        % Button pushed function: btnZoomAll
        function btnZoomAllButtonPushed(app, event)
          if isempty(app.wave); return; end
          app.setZoom(0,length(app.wave)-1);
        end

        % Button pushed function: btnPitchMark
        function btnPitchMarkButtonPushed(app, event)
          % Pre-checks and initialization
          if isempty(app.wave); return; end
          s = app.getSelection();
          if isempty(s); return; end
          k0 = s(1);
          k1 = s(2);
          if k0==k1; return; end
          assert(k0<k1);

          % Auto pitch-mark
          K0 = k1-k0;
          [pm,~] = vVZtools.autoPitchMark(app.wave,k0,K0,app.zcL,app.zcT);
          if isempty(pm)
            msg = ['Selection not appropriate. Try to select a cycle ' ...
                   'more precisely!'];
            title = 'Automatic Pitch Marking Failed';
            uialert(app.UIFigure,msg,title,'Icon','warning');
          else
            app.setPitchMarks(pm);          
          end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          if app.checkSave()
            delete(app);
          end
        end

        % Callback function: btnSave, mnSaveAnnotations
        function btnSaveButtonPushed(app, event)
          [status,msg] = app.saveAnnotations();
          if status==0
            title = 'Save Failed';
            uialert(app.UIFigure,msg,title,'Icon','error');
          else
            app.dirty = false;
          end
          app.updateUI();
        end

        % Button pushed function: btnWavebarInc
        function btnWavebarIncButtonPushed(app, event)
          if isempty(app.wave); return; end
          xl = app.UIAxes.XLim;
          K  = length(app.wave);
          L  = xl(2)-xl(1);
          k0 = xl(1)+app.scrInc*L;
          k1 = k0 + L;
          if k1>K-1; k1 = K-1; k0 = k1-L; end
          app.UIAxes.XLim = [k0 k1];
          app.updateUI();
        end

        % Button pushed function: btnWavebarDec
        function btnWavebarDecButtonPushed(app, event)
          if isempty(app.wave); return; end
          xl = app.UIAxes.XLim;
          L  = xl(2)-xl(1);
          k0 = xl(1)-app.scrInc*L;
          k1 = k0 + L;
          if k0<0; k0 = 0; k1 = k0+L; end
          app.UIAxes.XLim = [k0 k1];
          app.updateUI();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
          app.updateUI();
        end

        % Cell edit callback: tbProperties
        function tbPropertiesCellEdit(app, event)
          app.dirty = true;
          app.cleanupPropsTable();
          app.updateUI();
        end

        % Button pushed function: btnSortProperties
        function btnSortPropertiesButtonPushed(app, event)
          data = app.tbProperties.Data;
          data = sortrows(data,{'tier','value'});
          app.tbProperties.Data = data;
          app.cleanupPropsTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Name';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.mnOpenRecording = uimenu(app.FileMenu);
            app.mnOpenRecording.MenuSelectedFcn = createCallbackFcn(app, @mnOpenRecordingSelected, true);
            app.mnOpenRecording.Text = 'Open Recording';
            app.mnSaveAnnotations = uimenu(app.FileMenu);
            app.mnSaveAnnotations.MenuSelectedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.mnSaveAnnotations.Text = 'Save Annotations';
            app.mnExit = uimenu(app.FileMenu);
            app.mnExit.MenuSelectedFcn = createCallbackFcn(app, @mnExitSelected, true);
            app.mnExit.Text = 'Exit';
            app.glMain = uigridlayout(app.UIFigure);
            app.glToolbar = uigridlayout(app.glMain);
            app.btnZoomAll = uibutton(app.glToolbar, 'push');
            app.btnZoomAll.ButtonPushedFcn = createCallbackFcn(app, @btnZoomAllButtonPushed, true);
            app.btnZoomAll.Icon = 'zoomAll.png';
            app.btnZoomAll.Enable = 'off';
            app.btnZoomAll.Tooltip = {'Zoom all'};
            app.btnZoomAll.Text = '';
            app.btnZoomSel = uibutton(app.glToolbar, 'push');
            app.btnZoomSel.ButtonPushedFcn = createCallbackFcn(app, @btnZoomSelButtonPushed, true);
            app.btnZoomSel.Icon = 'zoomSel.png';
            app.btnZoomSel.Enable = 'off';
            app.btnZoomSel.Tooltip = {'Zoom to selection'};
            app.btnZoomSel.Text = '';
            app.btnPitchMark = uibutton(app.glToolbar, 'push');
            app.btnPitchMark.ButtonPushedFcn = createCallbackFcn(app, @btnPitchMarkButtonPushed, true);
            app.btnPitchMark.Enable = 'off';
            app.btnPitchMark.Text = 'Auto Pitch Mark';
            app.btnSave = uibutton(app.glToolbar, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Icon = 'save.png';
            app.btnSave.Enable = 'off';
            app.btnSave.Tooltip = {'Save annotations'};
            app.btnSave.Text = '';
            app.glStatusbar = uigridlayout(app.glMain);
            app.CursorEditFieldLabel = uilabel(app.glStatusbar);
            app.CursorEditFieldLabel.Text = 'Cursor';
            app.txCursor = uieditfield(app.glStatusbar, 'text');
            app.txCursor.Editable = 'off';
            app.DEBUGEditFieldLabel = uilabel(app.glStatusbar);
            app.DEBUGEditFieldLabel.Text = 'DEBUG';
            app.txDebug = uieditfield(app.glStatusbar, 'text');
            app.txDebug.Editable = 'off';
            app.TabGroup = uitabgroup(app.glMain);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.GridLayout = uigridlayout(app.SignalTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.glWavebar = uigridlayout(app.GridLayout);
            app.axWavebar = uiaxes(app.glWavebar);
            title(app.axWavebar, '')
            xlabel(app.axWavebar, '')
            ylabel(app.axWavebar, '')
            app.axWavebar.XTick = [];
            app.axWavebar.YTick = [];
            app.btnWavebarDec = uibutton(app.glWavebar, 'push');
            app.btnWavebarDec.ButtonPushedFcn = createCallbackFcn(app, @btnWavebarDecButtonPushed, true);
            app.btnWavebarDec.Enable = 'off';
            app.btnWavebarDec.Text = '<';
            app.btnWavebarInc = uibutton(app.glWavebar, 'push');
            app.btnWavebarInc.ButtonPushedFcn = createCallbackFcn(app, @btnWavebarIncButtonPushed, true);
            app.btnWavebarInc.Enable = 'off';
            app.btnWavebarInc.Text = '>';
            app.SliderLabel = uilabel(app.glWavebar);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.glWavebar);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @ScrollSliderValue, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @ScrollSliderValue, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout2 = uigridlayout(app.PropertiesTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.btnSortProperties = uibutton(app.GridLayout3, 'push');
            app.btnSortProperties.ButtonPushedFcn = createCallbackFcn(app, @btnSortPropertiesButtonPushed, true);
            app.btnSortProperties.Enable = 'off';
            app.btnSortProperties.Text = 'Sort';
            app.tbProperties = uitable(app.GridLayout2);
            app.tbProperties.ColumnName = {'tier'; 'value'};
            app.tbProperties.RowName = {};
            app.tbProperties.ColumnSortable = false;
            app.tbProperties.ColumnEditable = true;
            app.tbProperties.CellEditCallback = createCallbackFcn(app, @tbPropertiesCellEdit, true);
            app.tbProperties.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end