classdef pitchMark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        glMain                matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        glToolbar             matlab.ui.container.GridLayout
        btnZoomAll            matlab.ui.control.Button
        btnZoomSel            matlab.ui.control.Button
        btnPitchMark          matlab.ui.control.Button
        glStatusbar           matlab.ui.container.GridLayout
        CursorEditFieldLabel  matlab.ui.control.Label
        txCursor              matlab.ui.control.EditField
        DEBUGEditFieldLabel   matlab.ui.control.Label
        txDebug               matlab.ui.control.EditField
        glWavebar             matlab.ui.container.GridLayout
        axWavebar             matlab.ui.control.UIAxes
        btnWavebarDec         matlab.ui.control.Button
        btnWavebarInc         matlab.ui.control.Button
        SliderLabel           matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
    end

    properties (Access = private)
      % General                                    % -------------------
      DEBUG    = true;                             % Debug flag
      dirty    = false;                            % Dirty flag
      name     = 'Pitch Mark App';                 % App name
      zcL      = 50;                               % Zero-cross. detection window
      zcT      = 0.01;                             % Zero-cross. detection threshold
      dirHome  = '../';                            % vVZ home 
      dirWav   = 'waves/';                         % relative to dirHome
      dirLab   = 'labels/';                        % relative to dirHome
      
      % App state                                  % -------------------
      wave;                                        % Current wave data
      rid;                                         % Recording ID
      
      % Selection state                            % -------------------
      kSelDrag = -1;                               % Initial mouse drag sample index
      plSel    = [];                               % Plot selection
      
      % Pitch marks                                % -------------------
      plPm     = [];                               % Pitch marks

      % Wavebar state                              % -------------------
      kWbtDrag = -1;                               % Initial mouse drag sample index
      wbThumb  = [];                               % Wavebar thumb
      
      % Tool tip texts                             % -------------------
      ttApmEna = 'Automatically pitch-mark';
      ttApmDis = ['Automatically pitch-mark. '                       ...
                  'Disabled because selection is empty or a point. ' ...
                  'Select one cycle!'];
    end

    % UI utilities
    methods (Access = private)
        
      function updateUI(app)
        if isempty(app.wave)
          app.UIFigure.Name = app.name;
          app.btnZoomSel.Enable = false;
          app.btnZoomAll.Enable = false;
          app.btnPitchMark.Enable = false;
          app.btnPitchMark.Tooltip = app.ttApmDis;
          app.Slider.Enable = false;
          app.txCursor.Value = '(n/a)';
          app.updateWavebar();
          return;
        end
        K = length(app.wave);
        if app.dirty
          app.UIFigure.Name = [app.name ' - ' app.rid ' *'];
        else
          app.UIFigure.Name = [app.name ' - ' app.rid];
        end
        s = app.getSelection();
        if ~isempty(s) && s(1)~=s(2)
          app.btnZoomSel.Enable    = true;
          app.btnPitchMark.Enable  = true;
          app.btnPitchMark.Tooltip = app.ttApmEna;
        else
          app.btnZoomSel.Enable    = false;
          app.btnPitchMark.Enable  = false;
          app.btnPitchMark.Tooltip = app.ttApmDis;
        end
        xl = app.UIAxes.XLim;
        if xl(1)>0 || xl(2)<K-1  % Zoom-in state
          app.btnZoomAll.Enable = true;
          app.Slider.Enable = true;
          l = K-1-(xl(2)-xl(1));
          app.Slider.Value = xl(1)/l;
        else % Zoom-all state
          app.btnZoomAll.Enable = false;
          app.Slider.Enable = false;
          app.Slider.Value = 0;
        end
        app.updateWavebar();
      end
        
      function updateWavebar(app)
        if ~isempty(app.wbThumb)
          delete(app.wbThumb);
          app.wbThumb = [];
        end
        if ~isempty(app.wave)
          yl = app.axWavebar.YLim;
          xl = app.UIAxes.XLim;
          dim = [xl(1) yl(1) xl(2)-xl(1) yl(2)-yl(1)];
          app.wbThumb = rectangle(app.axWavebar,'Position',dim, ...
            'FaceColor',[0 0 0 0.2],'EdgeColor','none');
          app.axWavebar.YLim = yl;
        else
        end
      end
  
      function addToRecMenu(app,rid)
        r = strsplit(rid,'/');
        m = app.OpenMenu;
        for i=1:length(r)
          m = app.addSubMenu(m,r{i});
          if i==length(r)
            m.UserData = rid;          
          end
        end
      end
  
      function sm = addSubMenu(app,m,name)
        sm = 0;
        for i=1:length(m.Children)
          cld = m.Children(i);
          if strcmp(cld.Text,name)
            sm = cld;
          end
        end
        if sm==0
          sm = uimenu(m);
          sm.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
          sm.Text = name;
        end
      end

      function b = mouseInPlot(app)
        b  = false;
        cp = app.UIFigure.CurrentPoint;
        ap = app.UIAxes.InnerPosition;
        if cp(1)<ap(1) || cp(1)>ap(1)+ap(3) || ... % out of horizonal limits
           cp(2)<ap(2) || cp(2)>ap(2)+ap(4)        % out of vertical limits
          return;
        end
        b = true;
      end
 
      function BeginSelDrag(app)
        if app.mouseInPlot()
          k = round(app.UIAxes.CurrentPoint(1,1));
          app.kSelDrag = k;
          app.setSelection(k,k);
          app.selectionChanging(k,k);
        end
      end
 
      function SelDrag(app)
        if app.mouseInPlot() && app.kSelDrag>=0
          k = round(app.UIAxes.CurrentPoint(1,1));
          app.setSelection(k,app.kSelDrag);
          app.selectionChanging(k,app.kSelDrag);
        end
      end
 
      function EndSelDrag(app)
        if app.mouseInPlot()
          k = round(app.UIAxes.CurrentPoint(1,1));
          if app.kSelDrag>=0
            app.setSelection(k,app.kSelDrag);
            app.selectionChanged(k,app.kSelDrag);
          end
        end
        app.kSelDrag = -1;
      end
  
      function ok = checkSave(app)
        ok = true;
        if ~app.dirty; return; end
        msg   = 'Do you want to save changed pitch marks?';
        title = 'Confirm Save';
        sel = uiconfirm(app.UIFigure,msg,title, ...
              'Options',{'Save','Discard Changes','Cancel'},...
              'DefaultOption',1,'CancelOption',3);
        switch sel
          case 'Save'
            ok = true;
          case 'Discard Changes'
            ok = true;
          case 'Cancel'
            ok = false;
        end
      end
  
    end
    
    % UI Operations
    methods (Access = private)
  
      function setSelection(app,k0,k1)
        if ~isempty(app.wave)
          K  = length(app.wave);
          yl = app.UIAxes.YLim;
          if ~isempty(app.plSel)
            delete(app.plSel); 
            app.plSel = [];
          end
          if k0>=0 && k1>=0
            if k0>k1; k=k1; k1=k0; k0=k; end
            if k1>=K; k1=K-1; end
            if k0~=k1
              dim = [k0 yl(1) k1-k0 yl(2)-yl(1)];
              app.plSel = rectangle(app.UIAxes,'Position',dim, ...
                'FaceColor',[0 0.5 1 0.1],'EdgeColor','none');
            else
              dim = [k0 yl(1) 0 yl(2)-yl(1)];              
              app.plSel = rectangle(app.UIAxes,'Position',dim, ...
                'FaceColor','none','EdgeColor',[0 0.5 1]);
            end
            app.UIAxes.YLim = yl;
          end
        end
        app.updateUI();
      end
  
      function s = getSelection(app)
        s = [];
        if ~isempty(app.plSel)
          s(1) = app.plSel.Position(1);
          s(2) = s(1) + app.plSel.Position(3);
        end
      end
  
      function clearSelection(app)
        app.setSelection(-1,-1);
      end
  
      function setZoom(app,k0,k1)
        if isempty(app.wave); return; end
        K = length(app.wave);
        if k0>k1; k=k1; k1=k0; k0=k; end
        if k0< 0; k0 = 0;   end
        if k1>=K; k1 = K-1; end
        if k0==k1; return; end
        app.UIAxes.XLim = [k0 k1];
        app.updateUI();
      end
    
      function setPitchMarks(app,pm)
        app.clearPitchMarks();
        yl = app.UIAxes.YLim;
        for i=1:length(pm)
          k   = pm(i);
          dim = [k yl(1) 0 yl(2)-yl(1)];              
          app.plPm(i) = rectangle(app.UIAxes,'Position',dim, ...
            'FaceColor','none','EdgeColor',[1 0.75 0]);
        end
        app.UIAxes.YLim = yl;
        app.clearSelection();
        app.dirty = true;
        app.updateUI();
      end
  
      function clearPitchMarks(app)
        for i=1:length(app.plPm)
          delete(app.plPm(i));
        end
        app.plPm = [];
        app.dirty = true;
        app.updateUI();
      end
  
    end
    
    % Secondary callbacks
    methods (Access = private)
  
      function selectionChanging(app,k0,k1) %#ok
        % Nothing to be done...
      end
  
      function selectionChanged(app,k0,k1) %#ok
        % Nothing to be done...
      end
  
    end
    
    % Signal and label utilities
    methods (Access = private)
    
      function loadRecording(app,rid)
        if ~app.checkSave(); return; end
        app.clearSelection();
        cla(app.UIAxes,'reset');
        app.dirty = false;
        fn = strrep([app.dirWav '/' rid '.wav'],'\','/');
        fprintf('loadRecording(rid=\"%s\")\n',rid);
        fprintf('- \"%s\"\n',fn);
        app.wave = audioread(fn);
        app.rid  = rid;
        K = length(app.wave);
        fprintf('- %d samples\n',K);
        plot(app.UIAxes,0:K-1,app.wave,'Color','black');
        app.UIAxes.XLim = [0,K-1];
        plot(app.axWavebar,0:K-1,app.wave,'Color','black');
        app.axWavebar.XLim = [0,K-1];
        app.updateUI();
      end
  
    end
    
    % Static methods; could go to vVZtools.m
    methods (Static, Access=public)
  
      function kf = seekClosest(wave,k,L,fnc,varargin)
        % Seeks an event in a wave
        %
        %   kf = seekClosest(wave,k,L,@zeroCrossingF,l,t)
        %   kf = seekClosest(wave,k,L,@zeroCrossingR,l,t)
        %
        % arguments:
        %   wave     - A vector of samples
        %   k        - Zero-based sample index to start seeking from
        %   L        - Range to seek in: [k-L,k+L]
        %   fnc      - Detector function (see remarks)
        %   varargin - Additional arguments to detector function
        %
        % returns: 
        %   kf       - Zero-based sample index of closest event or
        %              -1 if no event has been found
        %
        % remarks:
        %   Valid detector functions have the signature
        %
        %      tf = fnc(wave,k,varargin)
        %
        %   where the return value is a Boolean indicating the detection
        %   result. Detector functions must cope with indexes k<0 and
        %   k>length(wave)-1 without throwing exceptions!
        
        kf = -1;
        for dk=0:L
          if fnc(wave,k+dk,varargin)
            kf = k+dk;
            return;
          end
          if fnc(wave,k-dk,varargin)
            kf = k-dk;
            return;
          end
        end
      end
        
      function tf = zeroCrossingF(wave,k,l,t)
        % Detects a falling-edge zero crossing.
        %
        %   tf = zeroCrossingF(wave,k,l,t)
        %
        % arguments:
        %   wave - A vector of samples
        %   k    - Zero-based sample index to examine
        %   l    - Detection window [k-l,k+l]
        %   t    - Detection threshold (samples values left of k must reach
        %          t, sample values right of k must reach -t)
        %
        % returns:
        %   tf   - True if a falling-edge zero crossing is at k,
        %          false otherwise
        
        if nargin==3 && iscell(l)          % Args.3,4 committed as cell array >>
          t = l{2};                        %   Get t
          l = l{1};                        %   Get l
        end                                % <<
        tf = false;                        % Initialize return value
        K  = length(wave);                 % Length wave
        if k<l || k>=K-l; return; end      % k too close to ends -> return
        i = k+1;                           % One-based sample index
        wl = wave(i-l:i-1);                % L samples left of k
        wr = wave(i:i+l);                  % L samples right of k
        if ~all(wl> 0); return; end        % non-positive left samples -> return
        if ~all(wr<=0); return; end        % positive right samples -> return
        if max(wl)< t ; return; end        % threshold missed left -> return
        if min(wr)>-t ; return; end        % threshold missed right -> return
        tf = true;                         % Yup - there's a zero cr. at k
      end
  
      function tf = zeroCrossingR(wave,k,l,t)
        % Detects a rising-edge zero crossing.
        %
        %   tf = zeroCrossingR(wave,k,l,t)
        %
        % arguments:
        %   wave - A vector of samples
        %   k    - Zero-based sample index to examine
        %   l    - Detection window [k-l,k+l]
        %   t    - Detection threshold (samples values left of k must reach
        %          t, sample values right of k must reach -t)
        %
        % returns:
        %   tf   - True if a rising-edge zero crossing is at k,
        %          false otherwise
        
        if nargin==3 && iscell(l)          % Args.3,4 committed as cell array >>
          t = l{2};                        %   Get t
          l = l{1};                        %   Get l
        end                                % <<
        tf = false;                        % Initialize return value
        K  = length(wave);                 % Length wave
        if k<l || k>=K-l; return; end      % k too close to ends -> return
        i = k+1;                           % One-based sample index
        wl = wave(i-l:i-1);                % L samples left of k
        wr = wave(i:i+l);                  % L samples right of k
        if ~all(wl< 0); return; end        % non-negtive left samples -> return
        if ~all(wr>=0); return; end        % negative right samples -> return
        if min(wl)>-t ; return; end        % threshold missed left -> return
        if max(wr)< t ; return; end        % threshold missed right -> return
        tf = true;                         % Yup - there's a zero cr. at k
      end

      function [pm,K0m] = autoPitchMark(wave,k0,K0,l,t)
        fprintf('autoPitchMark(k0=%d, K0=%d, l=%d, t=%f)\n',k0,K0,l,t);
        pm  = [];
        K0m = -1;
        
        % Pre-cheks
        if isempty(wave); return; end
        K = length(wave);
        if k0<0 || k0>=K
          warning('k0 must be in [0,%d]. ABORTING.',K-1);
          return;
        end
        if K0<=0
          warning('K must be positive. ABORTING.');
          return;
        end
          
        % Search initial zero-crossing near k0
        fprintf('- Pitch mark at cycle beginning: ');
        L   = round(K0/4); % Search range
        kzf = pitchMark.seekClosest(wave,k0,L,@pitchMark.zeroCrossingF,l,t);
        kzr = pitchMark.seekClosest(wave,k0,L,@pitchMark.zeroCrossingR,l,t);
        if kzf>=0 && kzr>=0
          if abs(k0-kzf)<=abs(k0-kzr)
            kz0 = kzf;
            falling = true;
          else
            kz0 = kzr;
            falling = false;
          end
        elseif kzf>=0
          kz0 = kzf;
          falling = true;
        elseif kzr>=0
          kz0 = kzr;
          falling = false;
        else
          fprintf('not found -> ABORT\n');
          return;
        end
        if falling
          fprintf('k=%d, falling edge\n',kz0);
        else
          fprintf('k=%d, rising edge\n',kz0);
        end

        % Seek zero-crossing near k0+K0
        if falling
          kz1 = pitchMark.seekClosest(wave,k0+K0,L,@pitchMark.zeroCrossingF,l,t);
        else
          kz1 = pitchMark.seekClosest(wave,k0+K0,L,@pitchMark.zeroCrossingR,l,t);
        end
        fprintf('- Pitch mark at cycle end      : ');
        if kz1<0
          fprintf('not found -> ABORT\n');
          return;
        else
          K0 = kz1-kz0;
          fprintf('k=%d, K0:=%d\n',kz1,K0);
        end

        % Seek further zero-crossings to the left
        k = kz0-K0;
        while k>0
          if falling
            kz = pitchMark.seekClosest(wave,k,L,@pitchMark.zeroCrossingF,l,t);
          else
            kz = pitchMark.seekClosest(wave,k,L,@pitchMark.zeroCrossingR,l,t);
          end
          if kz<0; break; end
          fprintf('- Pitch mark at at             : k=%d\n',kz);
          pm(length(pm)+1) = kz; %#ok
          k = kz-K0;
        end
        
        % Add initial pitch marks to output
        pm(length(pm)+1) = kz0;
        pm(length(pm)+1) = kz1;

        % Seek further zero-crossings to the right
        k = kz1+K0;
        while k<K-1
          if falling
            kz = pitchMark.seekClosest(wave,k,L,@pitchMark.zeroCrossingF,l,t);
          else
            kz = pitchMark.seekClosest(wave,k,L,@pitchMark.zeroCrossingR,l,t);
          end
          if kz<0; break; end
          fprintf('- Pitch mark at                : k=%d\n',kz);
          pm(length(pm)+1) = kz;
          k = kz+K0;
        end
        
        % Compute mean cylce length
        K0m = 0;
        for i=2:length(pm)
          K0m = K0m + (pm(i)-pm(i-1));
        end
        K0m = K0m/(length(pm)-1);
      end
  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % DEBUGGIN...
          if app.DEBUG
            close all; clc;
          end
            
          % Normalize folder names
          l = dir([app.dirHome app.dirWav]);
          app.dirWav = l(1).folder;
          l = dir([app.dirHome app.dirLab]);
          app.dirLab = l(1).folder;
          
          % Search wave files and build recordings list
          l = dir([app.dirWav '/**/*.wav']);
          for i=1:length(l)
            rec = strrep(l(i).folder,app.dirWav,'');
            rec = strrep(rec,'\','/');
            rec = [ rec(2:length(rec)) '/' strrep(l(i).name,'.wav','')];
            app.addToRecMenu(rec);
          end
          
          % Tweak UI
          app.UIAxes.Toolbar.Visible    = 'off';
          app.axWavebar.Visible         = 'off';
          app.axWavebar.Toolbar.Visible = 'off';
          set(0,'units','pixels') ;
          ss = get(0,'screensize');
          w  = round(0.95*ss(3));
          h  = 480;
          x  = round((ss(3)-w)/2);
          y  = round(ss(4)-h-round(0.1*h));
          app.UIFigure.Position = [x y w h];
          
          % Initial update
          app.updateUI();
        end

        % Callback function: UIFigure, UIFigure, UIFigure
        function UIFigureWindowButton(app, event)
          switch event.EventName
            case 'WindowMousePress'
              app.BeginSelDrag();
            case 'WindowMouseRelease'
              app.EndSelDrag();
            case 'WindowMouseMotion'
              app.SelDrag();
              if app.mouseInPlot() && ~isempty(app.wave)
                k = round(app.UIAxes.CurrentPoint(1,1));
                K = length(app.wave);
                if k< 0; k=0;   end
                if k>=K; k=K-1; end
                s = sprintf('k=%d, x(k)=%4.3f',k,app.wave(k+1));
                app.txCursor.Value = s;
              else
                app.txCursor.Value = '(n/a)';
              end
          end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
          if ~isempty(event.Source.UserData)
            app.loadRecording(event.Source.UserData);
          end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
          close(app.UIFigure);
        end

        % Callback function: Slider, Slider
        function ScrollSliderValue(app, event)
          if ~isempty(app.wave)
            v  = event.Value;
            K  = length(app.wave);
            xl = app.UIAxes.XLim;
            l  = K-1-(xl(2)-xl(1));
            app.UIAxes.XLim = [round(v*l) round(v*l)+xl(2)-xl(1)];
          else
          end
          app.updateUI();
        end

        % Button pushed function: btnZoomSel
        function btnZoomSelButtonPushed(app, event)
          if isempty(app.wave); return; end
          s = app.getSelection();
          if ~isempty(s)
            app.setZoom(s(1),s(2));
            app.clearSelection();
          end
        end

        % Button pushed function: btnZoomAll
        function btnZoomAllButtonPushed(app, event)
          if isempty(app.wave); return; end
          app.setZoom(0,length(app.wave)-1);
        end

        % Button pushed function: btnPitchMark
        function btnPitchMarkButtonPushed(app, event)
          % Pre-checks and initialization
          if isempty(app.wave); return; end
          s = app.getSelection();
          if isempty(s); return; end
          k0 = s(1);
          k1 = s(2);
          if k0==k1; return; end
          assert(k0<k1);

          % Auto pitch-mark
          K0 = k1-k0;
          [pm,~] = pitchMark.autoPitchMark(app.wave,k0,K0,app.zcL,app.zcT);
          if isempty(pm)
            msg = ['Selection not appropriate. Try to select a cycle ' ...
                   'more precisely!'];
            title = 'Automatic Pitch Marking Failed';
            uialert(app.UIFigure,msg,title,'Icon','warning');
          else
            app.setPitchMarks(pm);          
          end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.checkSave()
              delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Name';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButton, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.glMain = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.glMain);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.glToolbar = uigridlayout(app.glMain);
            app.btnZoomAll = uibutton(app.glToolbar, 'push');
            app.btnZoomAll.ButtonPushedFcn = createCallbackFcn(app, @btnZoomAllButtonPushed, true);
            app.btnZoomAll.Icon = 'zoomAll.png';
            app.btnZoomAll.Tooltip = {'Zoom all'};
            app.btnZoomAll.Text = '';
            app.btnZoomSel = uibutton(app.glToolbar, 'push');
            app.btnZoomSel.ButtonPushedFcn = createCallbackFcn(app, @btnZoomSelButtonPushed, true);
            app.btnZoomSel.Icon = 'zoomSel.png';
            app.btnZoomSel.Tooltip = {'Zoom to selection'};
            app.btnZoomSel.Text = '';
            app.btnPitchMark = uibutton(app.glToolbar, 'push');
            app.btnPitchMark.ButtonPushedFcn = createCallbackFcn(app, @btnPitchMarkButtonPushed, true);
            app.btnPitchMark.Text = 'Pitch Mark';
            app.glStatusbar = uigridlayout(app.glMain);
            app.CursorEditFieldLabel = uilabel(app.glStatusbar);
            app.CursorEditFieldLabel.Text = 'Cursor';
            app.txCursor = uieditfield(app.glStatusbar, 'text');
            app.txCursor.Editable = 'off';
            app.DEBUGEditFieldLabel = uilabel(app.glStatusbar);
            app.DEBUGEditFieldLabel.Text = 'DEBUG';
            app.txDebug = uieditfield(app.glStatusbar, 'text');
            app.txDebug.Editable = 'off';
            app.glWavebar = uigridlayout(app.glMain);
            app.axWavebar = uiaxes(app.glWavebar);
            title(app.axWavebar, '')
            xlabel(app.axWavebar, '')
            ylabel(app.axWavebar, '')
            app.axWavebar.XTick = [];
            app.axWavebar.YTick = [];
            app.btnWavebarDec = uibutton(app.glWavebar, 'push');
            app.btnWavebarDec.Text = '<';
            app.btnWavebarInc = uibutton(app.glWavebar, 'push');
            app.btnWavebarInc.Text = '>';
            app.SliderLabel = uilabel(app.glWavebar);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.glWavebar);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @ScrollSliderValue, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @ScrollSliderValue, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pitchMark

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end